classdef home_help_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        CloseButton  matlab.ui.control.Button
        Label        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            txt = "Welcome to palletizing simulator. This screen will introduce you to this software.";
            txt = txt + newline + newline + newline +"- By pressing Run Simulator, the robot runs a palletizing simulation code. Before pressing it,";
            txt = txt + newline + "ensure that the module on RobotStudio is running.";
            txt = txt + newline + newline +"- The first run of the bottle/box detection are usually not accurate";
            txt = txt + newline + "which is caused by the delay in camera. If this happens, press Repeat Current Detection for better result.";
            txt = txt + newline + newline +"- If pressing Repeat Current Detection button does not fix the problem, please press Stop Operation, then Setting.";
            
            txt = txt + newline + newline +"- Other task button takes you to an interface that lets you do things other than palletizing using the robot.";
            txt = txt + newline + "Press it and find out!";
            
            app.Label.Text = txt;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Create Help Dialogue When everything is done';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home_help_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end