classdef home_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HelpMenu                      matlab.ui.container.Menu
        RepeatCurrentDetectionButton  matlab.ui.control.StateButton
        StopOperationButton           matlab.ui.control.StateButton
        ContinueButton                matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        RunSimulatorButton            matlab.ui.control.Button
        SettingButton                 matlab.ui.control.Button
        OtherTasksButton              matlab.ui.control.Button
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        cam
        colDet
        capDet
        converter
        boxDet
        
        setting
        help
        other
    end
    
    methods (Access = public)
        function success =  move2Cam(app)
            try
                app.Label.Text = "Connecting to server";
                IP = "192.168.0.20";
                port = 1025;
                tcp = tcpclient(IP, port);
                str = "Connected";
                tcp.write(str);
                tcp.flush;
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "RSConnected"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        app.Label.Text = "something went wrong, please try again";
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                app.Label.Text = "Successfuly connected to server";
                tcp.write("1");
                tcp.flush();
                flag = 0;
                count = 0;
                app.Label.Text = "Moving robot to camera position. Please wait";
                while flag == 0
                    str = read(tcp);
                    if char(str) == "CamPos"
                        break
                    end
                    pause(0.5);
                    count = count + 1;
                    if count > 30
                        ME = MException("receivingDataError", "Error receiving RobotStudio.");
                        app.Label.Text = "something went wrong, please try again";
                        clear tcp
                        throw(ME);
                    end
                end
                clear tcp
                success = true;
            catch ME
                if ME.identifier == "receivingDataError"
                    warndlg(ME.message);
                elseif ME.identifier == "connectionError"
                    warndlg(ME.message);
                else
                    warndlg("there was an error sending signal to move to Camera Position")
                end
                success = false;
                return
            end
        end

        function success = move2Box(app)
            try
                app.Label.Text = "Connecting to server";
                IP = "192.168.0.20";
                port = 1025;
                tcp = tcpclient(IP, port);
                str = "Connected";
                tcp.write(str);
                tcp.flush;
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "RSConnected"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        app.Label.Text = "something went wrong, please try again";
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                app.Label.Text = "Successfuly connected to server";
                tcp.write("4");
                tcp.flush();
                flag = 0;
                count = 0;
                app.Label.Text = "Moving robot to box camera position. Please wait";
                while flag == 0
                    str = read(tcp);
                    if char(str) == "AtBox"
                        break
                    end
                    pause(0.5);
                    count = count + 1;
                    if count > 90
                        ME = MException("receivingDataError", "Error receiving RobotStudio.");
                        app.Label.Text = "something went wrong, please try again";
                        clear tcp
                        throw(ME);
                    end
                end
                clear tcp
                success = true;
            catch ME
                if ME.identifier == "receivingDataError"
                    warndlg(ME.message);
                elseif ME.identifier == "connectionError"
                    warndlg(ME.message);
                else
                    warndlg("there was an error sending signal to move to Camera Position")
                end
                success = false;
                return
            end
        end


        function success = move2Bot(app, capList, emptySlots)
            try
                mode = 1;
                if width(capList) > height(emptySlots)
                    mode = 0;
                end
                app.Label.Text = "Connecting to server";
                tcp = tcpclient("192.168.0.20", 1025);
                tcp.write("Connected");
                tcp.flush;
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "RSConnected"
                        break
                    end
                    pause(0.5);
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        app.Label.Text = "something went wrong, please try again";
                        clear tcp
                        throw(ME);
                    end
                end
                app.Label.Text = "Successfuly connected to server";
                tcp.write("2");
                tcp.flush();
                
                brownCount = 0;
                redCount = 0;
                for h = 1:width(capList)
                    str = "";
                    if h > height(emptySlots) 
                        break
                    end
                    
                    if capList(h).colour == "Brown"
                        if mode == 1
                            brownCount = brownCount +1;
                            angle = emptySlots(brownCount,3);
                            angle = rad2deg(angle);
                            orientation = emptySlots(brownCount,4);
                            ori = num2str(orientation);
                            str = "B," + num2str(capList(h).x) + "," + num2str(capList(h).y) +","+num2str(emptySlots(brownCount,1)) + "," +num2str(emptySlots(brownCount,2)) + "," + num2str(angle) + "," + ori;
                        else
                            brownCount = brownCount +1;
                            angle = emptySlots(h,3);
                            angle = rad2deg(angle);
                            orientation = emptySlots(h,4);
                            ori = num2str(orientation);
                            str = "B," + num2str(capList(h).x) + "," + num2str(capList(h).y) +","+num2str(emptySlots(h,1)) + "," +num2str(emptySlots(h,2)) + "," + num2str(angle) + "," + ori;
                        end
                    else
                        if mode == 1 
                            redCount = redCount + 1;
                            if height(emptySlots) > 6 
                                angle = emptySlots(redCount+6,3);
                                angle = rad2deg(angle);
                                orientation = emptySlots(redCount+6,4);
                                ori = num2str(orientation);
                                str = "R," + num2str(capList(h).x) + "," + num2str(capList(h).y) +","+num2str(emptySlots((redCount+6),1)) + "," +num2str(emptySlots(redCount+6,2)) + "," + num2str(angle) + "," + ori;
                            else
                                angle = emptySlots(h,3);
                                angle = rad2deg(angle);
                                orientation = emptySlots(h,4);
                                ori = num2str(orientation);
                                str = "R," + num2str(capList(h).x) + "," + num2str(capList(h).y) +","+num2str(emptySlots(h,1)) + "," +num2str(emptySlots(h,2)) + "," + num2str(angle) + "," + ori;
                            end
                        else
                            angle = emptySlots(h,3);
                            angle = rad2deg(angle);
                            orientation = emptySlots(h,4);
                            ori = num2str(orientation);
                            redCount = redCount + 1;
                            str = "R," + num2str(capList(h).x) + "," + num2str(capList(h).y) +","+num2str(emptySlots(h,1)) + "," +num2str(emptySlots(h,2)) + "," + num2str(angle) + "," + ori;
                        end
                    end
                    disp(str);
                    app.Label.Text = "Sending coordinate data to the robot";
                    tcp.write(str);
                    tcp.flush();
                    flag = 0;
                    app.Label.Text = "Waiting for robot response";
                    count = 0;
                    while flag == 0
                        stri = read(tcp);
                        if char(stri) == "SendNext"
                            disp("sending next coordinate")
                            flag = 1;
                        end
                        pause(0.5);
                        count = count+1;
                        if count > 90
                        ME = MException("connectionError", "Failed to get a response from the host");
                        app.Label.Text = "something went wrong, please try again";
                        clear tcp
                        throw(ME);
                        end
                    end
                end
                tcp.write("3");
                clear tcp
                success = true;
            catch ME
                if ME.identifier == "connectionError"
                    warndlg(ME.message);
                else
                    warndlg("there was an error sending signal to move to Bottle Position")
                end
                
                success = false;
                return
            end
        end
        
        function results = disableButtons(app)
            app.HelpMenu.Enable = false;
            app.SettingButton.Enable = false;
            app.RunSimulatorButton.Enable = false;
            app.OtherTasksButton.Enable = false;
            results = true;
            return
        end

        function results = enableButtons(app)
            app.HelpMenu.Enable = true;
            app.SettingButton.Enable = true;
            app.RunSimulatorButton.Enable = true;
            app.OtherTasksButton.Enable = true;
            results = true;
            return
        end

        function results = copyObjects(app, camera, capDet, colDet, boxDet)
            app.cam = camera;
            app.capDet = capDet;
            app.colDet = colDet;
            app.boxDet = boxDet;
            app.cam
            app.capDet
            app.colDet
            app.boxDet
            results = true;
        end

        function results = cheese(app)
            img = app.cam.tempImageAcq(1,'l','3840x1080', 4, 4, 4, 4, 5);
            app.Image.ImageSource = img;
            results =true;
            return
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cam = Camera();
            app.colDet = Colour_Detection();
            app.capDet = Cap_Detection();
            app.converter = Coordinate_Converter();
            app.boxDet = Box_Detection();
            try
                img = app.cam.tempImageAcq(1,'l','3840x1080', 4, 4, 4, 4, 5);
                app.Image.ImageSource = img;
            catch
                return
            end
        end

        % Button pushed function: SettingButton
        function SettingButtonPushed(app, event)
            app.disableButtons();
            app.setting = setting_interface(app, app.cam, app.capDet, app.colDet, app.boxDet);
        end

        % Button pushed function: OtherTasksButton
        function OtherTasksButtonPushed(app, event)
            app.disableButtons();
            app.other = other_task_interface(app);    
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.help = home_help_interface();
        end

        % Button pushed function: RunSimulatorButton
        function RunSimulatorButtonPushed(app, event)
            app.disableButtons();
            success = app.move2Cam();
            if success == false
                app.enableButtons();
                return
            end
            repeat = true;
            while repeat == true
                imD = app.cam.imageAcq(1, 'l');
                caps = app.capDet.detectCaps(imD);
                if height(caps) == 0 
                    app.Label.Text = "Ensure that there are bottles on the table (within the camera frame) or go through the settings.";
                    app.Image.ImageSource = imD;
                    app.enableButtons();
                    return
                end
                capL = app.colDet.detectColour(imD, caps(:,1:2), caps(:,3), app.capDet.dia);
                if width(capL) == 0 
                    app.Label.Text = "Ensure that there are bottles on the table (within the camera frame) or go through the settings.";
                    app.Image.ImageSource = imD;
                    app.enableButtons();
                    return
                end
                capL2 = app.colDet.eliminateDuplicate(capL, 100);
                imN = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
                img = app.colDet.visualiseAnalysis(capL2, imN);
                app.Image.ImageSource = img;
                for w = 1:width(capL2)
                    if capL2(w).colour == "Brown"
                        [capL2(w).x, capL2(w).y] = app.converter.convertBrown(capL2(w).x, capL2(w).y);
                    else
                        [capL2(w).x, capL2(w).y] = app.converter.convertRed(capL2(w).x, capL2(w).y);
                    end
                    % -20 and 163 comes from distance between the camera and
                    % the gripper
                    cam2Grip = app.converter.convertDirection(-20, 163, ((3*pi)/4));
                    % 215 and -680 come from the physical coordiante of the
                    % camera position respect to base of the robot.
                    capL2(w).x = capL2(w).x + cam2Grip(1) + 215;
                    capL2(w).y = capL2(w).y + cam2Grip(2) - 680.3;
                end
                txt = "there are " + width(capL2) + " caps detected. Would you like to continue?";
                app.Label.Text = txt;
                app.ContinueButton.Enable = true;
                app.StopOperationButton.Enable = true;
                app.RepeatCurrentDetectionButton.Enable = true;
                while app.ContinueButton.Value ~= 1 && app.StopOperationButton.Value ~= 1 && app.RepeatCurrentDetectionButton.Value ~= 1
                    pause(0.5)
                end
                if app.StopOperationButton.Value == 1
                    app.StopOperationButton.Value = 0;
                    app.ContinueButton.Enable = false;
                    app.StopOperationButton.Enable = false;
                    repeat = false;
                    app.RepeatCurrentDetectionButton.Enable = false;
                    app.enableButtons();
                    return
                elseif app.ContinueButton.Value == 1
                    app.ContinueButton.Value = 0;
                    app.ContinueButton.Enable = false;
                    repeat = false;
                    app.RepeatCurrentDetectionButton.Enable = false;
                    app.StopOperationButton.Enable = false;
                else
                    app.RepeatCurrentDetectionButton.Value = 0;
                    app.ContinueButton.Enable = false;
                    app.RepeatCurrentDetectionButton.Enable = false;
                    app.StopOperationButton.Enable = false;
                end
            end
            success = app.move2Box();
            if success == false
                app.enableButtons();
                return
            end
            repeat = true;
            while repeat == true
                imBox = app.cam.tempImageAcq(1,'l', '3840x1080', 0, 8, 8, 8, 1);
                slots = app.boxDet.detectBox(imBox);
                if height(slots) == 0 
                    app.Label.Text = "Ensure that there are boxes on the table (within the camera frame), or go through the setting.";
                    app.Image.ImageSource = imBox;
                    app.enableButtons();
                    return
                end
                newImage = app.boxDet.visualiseSlots(imBox, slots);
                app.Image.ImageSource = newImage;
                cam2Grip = app.converter.convertDirection(-1, 158.125, (pi/4)+((2/180)*pi));
                for h= 1:height(slots)
                    [slots(h,1), slots(h,2)] = app.converter.convertBox(slots(h,1), slots(h,2));
                    slots(h,1) = slots(h,1) + cam2Grip(1) + 211.5963;
                    slots(h,2) = slots(h,2) + cam2Grip(2) + 832.1431;
                end
    
                app.Label.Text = "There are " + num2str(height(slots)) + " bottle slots detected. would you like to continue?";
                app.ContinueButton.Enable = true;
                app.StopOperationButton.Enable = true;
                app.RepeatCurrentDetectionButton.Enable = true;
                while app.ContinueButton.Value ~= 1 && app.StopOperationButton.Value ~= 1 && app.RepeatCurrentDetectionButton.Value ~= 1
                    pause(0.5)
                end
                if app.StopOperationButton.Value == 1
                    app.StopOperationButton.Value = 0;
                    app.ContinueButton.Enable = false;
                    app.StopOperationButton.Enable = false;
                    app.RepeatCurrentDetectionButton.Enable = false;
                    repeat = false;
                    app.enableButtons();
                    return
                elseif app.ContinueButton.Value == 1
                    app.ContinueButton.Value = 0;
                    app.ContinueButton.Enable = false;
                    app.RepeatCurrentDetectionButton.Enable = false;
                    repeat = false;
                    app.StopOperationButton.Enable = false;
                else
                    app.RepeatCurrentDetectionButton.Value = 0;
                    app.ContinueButton.Enable = false;
                    app.RepeatCurrentDetectionButton.Enable = false;
                    app.StopOperationButton.Enable = false;
                end
            end
            slots
            app.move2Bot(capL2, slots);
            app.Label.Text = "Please press Run Simulator, if the detection needs tuning, go to Setting.";
            app.enableButtons();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app)
            catch
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.Image = uiimage(app.UIFigure);
            app.OtherTasksButton = uibutton(app.UIFigure, 'push');
            app.OtherTasksButton.ButtonPushedFcn = createCallbackFcn(app, @OtherTasksButtonPushed, true);
            app.OtherTasksButton.Text = 'Other Tasks';
            app.SettingButton = uibutton(app.UIFigure, 'push');
            app.SettingButton.ButtonPushedFcn = createCallbackFcn(app, @SettingButtonPushed, true);
            app.SettingButton.Text = 'Setting';
            app.RunSimulatorButton = uibutton(app.UIFigure, 'push');
            app.RunSimulatorButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulatorButtonPushed, true);
            app.RunSimulatorButton.Text = 'Run Simulator';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Welcome to Palletizing simulator. Please press Run Simulator, if the detection needs tuning, go to Setting.';
            app.ContinueButton = uibutton(app.UIFigure, 'state');
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            app.StopOperationButton = uibutton(app.UIFigure, 'state');
            app.StopOperationButton.Enable = 'off';
            app.StopOperationButton.Text = 'Stop Operation';
            app.RepeatCurrentDetectionButton = uibutton(app.UIFigure, 'state');
            app.RepeatCurrentDetectionButton.Enable = 'off';
            app.RepeatCurrentDetectionButton.Text = 'Repeat Current Detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = home_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end