classdef other_task_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        HelpMenu                   matlab.ui.container.Menu
        TakephotoofaudienceButton  matlab.ui.control.Button
        WriteonwhiteboardButton    matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        home % Description
        cam
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, camera)
            app.home = callingApp; 
            app.cam = camera;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            enableButtons(app.home);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            enableButtons(app.home);
            delete(app);
        end

        % Button pushed function: WriteonwhiteboardButton
        function WriteonwhiteboardButtonPushed(app, event)
            writing_interface();
            
        end

        % Button pushed function: TakephotoofaudienceButton
        function TakephotoofaudienceButtonPushed(app, event)
            app.TakephotoofaudienceButton.Enable = false;
            app.WriteonwhiteboardButton.Enable = false;
            app.CloseButton.Enable = false;

            try
                IP = "192.168.0.20";
                port = 1025;
                app.Label.Text = "Connecting to the robot server. Please wait.";
                tcp = tcpclient(IP, port);
                str = "Connected";
                tcp.write(str);
                tcp.flush;
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "RSConnected"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                app.Label.Text = "Connected to server.";
                tcp.write("7");
                flag = 0;
                count = 0;
                while flag == 0
                    str = read(tcp);
                    if char(str) == "InSeven"
                        break
                    end
                    count = count+1;
                    if count > 30
                        ME = MException("connectionError", "Failed to get a response from the host");
                        clear tcp
                        throw(ME);
                    end
                    pause(0.5);
                end
                app.Label.Text = "3";
                pause(1);
                app.Label.Text = "2";
                pause(1);
                app.Label.Text = "1";
                pause(1);
                app.Label.Text = "Cheese!";
                img = app.cam.tempImageAcq(1,'l','3840x1080', 4, 4, 4, 4, 5);
                figure;
                imashow(img)
            catch ME
                if ME.identifier == "connectionError"
                    warndlg("Cannot receive signal from server");
                    app.TakephotoofaudienceButton.Enable = true;
                    app.WriteonwhiteboardButton.Enable = true;
                    app.CloseButton.Enable = true;
                    clear tcp
                    return
                else
                    warndlg("Cannot connect to server");
                    app.TakephotoofaudienceButton.Enable = true;
                    app.WriteonwhiteboardButton.Enable = true;
                    app.CloseButton.Enable = true;
                    clear tcp
                    return
                end
            end
            clear tcp
            app.TakephotoofaudienceButton.Enable = true;
            app.WriteonwhiteboardButton.Enable = true;
            app.CloseButton.Enable = true;
            app.Label.Text = "You can make the robot do cool things other than palletizing in this section!";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'You can make the robot do cool things other than palletizing in this section!';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WriteonwhiteboardButton = uibutton(app.UIFigure, 'push');
            app.WriteonwhiteboardButton.ButtonPushedFcn = createCallbackFcn(app, @WriteonwhiteboardButtonPushed, true);
            app.WriteonwhiteboardButton.Text = 'Write on white board';
            app.TakephotoofaudienceButton = uibutton(app.UIFigure, 'push');
            app.TakephotoofaudienceButton.ButtonPushedFcn = createCallbackFcn(app, @TakephotoofaudienceButtonPushed, true);
            app.TakephotoofaudienceButton.Text = 'Take photo of audience';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = other_task_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end