classdef set_diameter_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label                 matlab.ui.control.Label
        CancelButton          matlab.ui.control.Button
        RestoreDefaultButton  matlab.ui.control.Button
        ConfirmButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp
        newDia;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, newDia, curDia, callingApp)
            txt = "Would you like to update the bottle detection diameter to " + num2str(newDia) + "pixels?";
            txt = txt + newline+" (current diameter is " + num2str(curDia) + "pixels)";
            app.Label.Text = txt;
            app.newDia = newDia;
            app.callingApp = callingApp;
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            app.callingApp.setDia(app.callingApp.getDefDia());
            caps = findCaps(app.callingApp);
            changeDiameterLabel(app.callingApp, app.callingApp.getDefDia());
            changeImage(app.callingApp, caps);
            enableSliders(app.callingApp);
            delete(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.callingApp.setDia(app.newDia);
            enableSliders(app.callingApp);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            enableSliders(app.callingApp);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_diameter_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end