classdef set_length_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label                 matlab.ui.control.Label
        CancelButton          matlab.ui.control.Button
        RestoreDefaultButton  matlab.ui.control.Button
        ConfirmButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        boxDet; % Description
        callingApp
        nl
        np
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, boxDet, newl, newp)
            app.boxDet = boxDet;
            app.callingApp = callingApp;
            text = "Current horizontal dist = " + num2str(app.boxDet.lToEdge) + "pixels. New one is " + num2str(newl) + " pixels";
            text = text + newline + "Current vertical dist = " + num2str(app.boxDet.pToEdge) + "pixels. New one is " + num2str(newp) + " pixels";
            text = text + newline + "Would you like to update?";
            app.Label.Text = text;
            app.nl = newl;
            app.np = newp;
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            [p,l,b,e, lo, sh] = app.boxDet.getDefaultVal();
            app.boxDet = app.boxDet.setDist2Edge(p, l);
            updateboxdet(app.callingApp, app.boxDet);
            enableSliders(app.callingApp);
            delete(app);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.boxDet = app.boxDet.setDist2Edge(app.np, app.nl);
            updateboxdet(app.callingApp, app.boxDet);
            enableSliders(app.callingApp);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            enableSliders(app.callingApp);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_length_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end