classdef setting_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HelpMenu                      matlab.ui.container.Menu
        stateLabel                    matlab.ui.control.Label
        iconLabel                     matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        ApplyChangesButton            matlab.ui.control.Button
        RestoreDefaultButton          matlab.ui.control.Button
        readyIcon                     matlab.ui.control.Image
        previewImage                  matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        CapDetectionTab               matlab.ui.container.Tab
        SensitivitySettingPanel       matlab.ui.container.Panel
        EdgeThresholdSlider           matlab.ui.control.Slider
        EdgeThresholdSliderLabel      matlab.ui.control.Label
        SensitivitySlider             matlab.ui.control.Slider
        SensitivitySliderLabel        matlab.ui.control.Label
        CameraSettingPanel            matlab.ui.container.Panel
        GammaSlider                   matlab.ui.control.Slider
        GammaSliderLabel              matlab.ui.control.Label
        SaturationSlider              matlab.ui.control.Slider
        SaturationSliderLabel         matlab.ui.control.Label
        SharpnessSlider               matlab.ui.control.Slider
        SharpnessSliderLabel          matlab.ui.control.Label
        ContrastSlider                matlab.ui.control.Slider
        ContrastSliderLabel           matlab.ui.control.Label
        BrightnessSlider              matlab.ui.control.Slider
        BrightnessSliderLabel         matlab.ui.control.Label
        CapDiameterTab                matlab.ui.container.Tab
        ChangeButton                  matlab.ui.control.Button
        DiameterLabel                 matlab.ui.control.Label
        ColourDetectionTab            matlab.ui.container.Tab
        RedCapRGBlimitsPanel          matlab.ui.container.Panel
        BLowerLimitSpinner_2          matlab.ui.control.Spinner
        BLowerLimitSpinner_2Label     matlab.ui.control.Label
        BUpperLimitSpinner_2          matlab.ui.control.Spinner
        BUpperLimitSpinner_2Label     matlab.ui.control.Label
        GLowerLimitSpinner_2          matlab.ui.control.Spinner
        GLowerLimitSpinner_2Label     matlab.ui.control.Label
        GUpperLimitSpinner_2          matlab.ui.control.Spinner
        GUpperLimitSpinner_2Label     matlab.ui.control.Label
        RLowerLimitSpinner_2          matlab.ui.control.Spinner
        RLowerLimitSpinner_2Label     matlab.ui.control.Label
        RUpperLimitSpinner_2          matlab.ui.control.Spinner
        RUpperLimitSpinner_2Label     matlab.ui.control.Label
        BrownCapRGBlimitsPanel        matlab.ui.container.Panel
        PleaseDONOTspamthespinnerarrowLabel  matlab.ui.control.Label
        BLowerLimitSpinner            matlab.ui.control.Spinner
        BLowerLimitSpinnerLabel       matlab.ui.control.Label
        BUpperLimitSpinner            matlab.ui.control.Spinner
        BUpperLimitSpinnerLabel       matlab.ui.control.Label
        GLowerLimitSpinner            matlab.ui.control.Spinner
        GLowerLimitSpinnerLabel       matlab.ui.control.Label
        GUpperLimitSpinner            matlab.ui.control.Spinner
        GUpperLimitSpinnerLabel       matlab.ui.control.Label
        RLowerLimitSpinner            matlab.ui.control.Spinner
        RLowerLimitSpinnerLabel       matlab.ui.control.Label
        RUpperLimitSpinner            matlab.ui.control.Spinner
        RUpperLimitSpinnerLabel       matlab.ui.control.Label
        BoxDetectionTab               matlab.ui.container.Tab
        DisttoslotlongEditField       matlab.ui.control.NumericEditField
        DisttoslotlongEditFieldLabel  matlab.ui.control.Label
        DisttoslotshortEditField      matlab.ui.control.NumericEditField
        DisttoEdgehorizontalLabel     matlab.ui.control.Label
        EdgeconnecterSlider           matlab.ui.control.Slider
        EdgeconnecterSliderLabel      matlab.ui.control.Label
        BrightnessThresholdSpinner    matlab.ui.control.Spinner
        BrightnessThresholdSpinnerLabel  matlab.ui.control.Label
        BoxLengthTab                  matlab.ui.container.Tab
        ChangeButton_2                matlab.ui.control.Button
        Label                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        cam
        colDet
        capDet
        boxDet

        home% Description
        diaSetter
        lenSetter
    end
    
    methods (Access = private)
        function rgb = bw2rgb(app, img)
            rgb = [];
            for x = 1:height(img)
                for y = 1:width(img)
                    if img(x,y) == 0
                        rgb(x,y,1) = 0;
                        rgb(x,y,2) = 0;
                        rgb(x,y,3) = 0;
                    else
                        rgb(x,y,1) = 255;
                        rgb(x,y,2) = 255;
                        rgb(x,y,3) = 255;
                    end

                end
            end
            return
        end
        function results = disableSliders(app)
            app.BrightnessSlider.Enable = false;
            app.ContrastSlider.Enable = false;
            app.SaturationSlider.Enable = false;
            app.SharpnessSlider.Enable = false;
            app.GammaSlider.Enable = false;
            app.SensitivitySlider.Enable = false;
            app.EdgeThresholdSlider.Enable = false;

            app.ChangeButton.Enable = false;

            app.RLowerLimitSpinner.Enable = false;
            app.RUpperLimitSpinner.Enable = false;
            app.RLowerLimitSpinner_2.Enable = false;
            app.RUpperLimitSpinner_2.Enable =false;

            app.GLowerLimitSpinner.Enable = false;
            app.GUpperLimitSpinner_2.Enable = false;
            app.GLowerLimitSpinner_2.Enable = false;
            app.GUpperLimitSpinner.Enable = false;

            app.BLowerLimitSpinner.Enable = false;
            app.BLowerLimitSpinner_2.Enable = false;
            app.BUpperLimitSpinner.Enable = false;
            app.BUpperLimitSpinner_2.Enable = false;

            app.RestoreDefaultButton.Enable = false;
            app.ApplyChangesButton.Enable = false;
            app.CloseButton.Enable = false;
            
            app.BrightnessThresholdSpinner.Enable = false;
            app.EdgeconnecterSlider.Enable = false;
            app.DisttoslotlongEditField.Enable = false;
            app.DisttoslotshortEditField.Enable = false;

            results = true;
            return
        end
        function changeIcon(app, capNum, mode)
            if mode == 1
                if capNum == 10 
                    txt = "There are 10 caps detected with current settings. "; 
                    txt = txt + newline + "Go to Box Detection tab!";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Caps_ready.png";
                else
                    txt = "There are "+ num2str(capNum) + " caps detected with current settings.";
                    txt = txt + newline + "Note that 10 bottles are expected to be used in this software, if you see that the software";
                    txt = txt + newline +  "has detected all the bottles on the table, please move on to Box Detection tab";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Caps_not_ready.png";
                end
            elseif mode == 2
                if capNum > 10
                    txt = "There are "+ num2str(capNum) + " caps detected with current settings.";
                    txt = txt + newline + "Go to Colour Detection tab to chack the colour detection!";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Caps_not_ready.png";
                else
                    txt = "There are "+ num2str(capNum) + " caps detected with current settings.";
                    txt = txt + newline + "Hint: increase Sensitivity or Edge Threshold!";
                    txt = txt + newline + "Note that 10 bottles are expected to be used in this software, if you see that the software";
                    txt = txt + newline +  "has detected all the bottles on the table, please move on to Box Detection tab";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Caps_not_ready.png";
                end
            else
                if capNum == 12 || capNum == 6
                    txt = "There are " + num2str(capNum/6) + " boxes detected with current setting.";
                    txt = txt + newline + "If you are happy with the detection, press Apply Changes then close.";
                    txt = txt + newline + "If there are error in the detection, please keep adjusting the setting.";
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Colours_ready.png";
                else
                    txt = "There are" + num2str(capNum/6) + " boxes detected with current setting."; 
                    app.iconLabel.Text = txt;
                    app.readyIcon.ImageSource = "Caps_ready.png";
                end
            end
            app.enableSliders();
        end
        function capList = findColours(app)
            app.disableSliders();
            imD = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));            
            caps = app.capDet.detectCaps(imD);
            height(caps)
            if height(caps) == 0
                app.stateLabel.Text = "Make sure there are bottles on the table, or go back to Cap Detection tab to detect bottles before adjusting colours";
                app.enableSliders
                capList = [];
                return
            end
            cl = app.colDet.tempDetectColour(imD, caps(:,1:2), caps(:,3), app.capDet.dia, [app.RLowerLimitSpinner.Value app.RUpperLimitSpinner.Value], [app.GLowerLimitSpinner.Value app.GUpperLimitSpinner.Value], [app.BLowerLimitSpinner.Value app.BUpperLimitSpinner.Value], ...
                [app.RLowerLimitSpinner_2.Value app.RUpperLimitSpinner_2.Value], [app.GLowerLimitSpinner_2.Value app.GUpperLimitSpinner_2.Value], [app.BLowerLimitSpinner_2.Value app.BUpperLimitSpinner_2.Value]);
            if width(cl) == 0
                app.stateLabel.Text = "Make sure there are bottles on the table, or go back to Cap Detection tab to detect bottles before adjusting colours";
                app.enableSliders
                capList = [];
                return
            end
            capList = app.colDet.eliminateDuplicate(cl, 100);
            return
        end
    end

    methods (Access = public)
        function success = setDia(app, newDia)
            app.capDet = app.capDet.setDia(newDia);
            success = true;
            return
        end

        function defDia = getDefDia(app)
            defVars = app.capDet.getDefVars();
            defDia = defVars(1);
            return
        end
        function results = enableSliders(app)
            app.BrightnessSlider.Enable = true;
            app.ContrastSlider.Enable = true;
            app.SaturationSlider.Enable = true;
            app.SharpnessSlider.Enable = true;
            app.GammaSlider.Enable = true;
            app.SensitivitySlider.Enable = true;
            app.EdgeThresholdSlider.Enable = true;

            app.ChangeButton.Enable = true;

            app.RLowerLimitSpinner.Enable = true;
            app.RUpperLimitSpinner.Enable = true;
            app.RLowerLimitSpinner_2.Enable = true;
            app.RUpperLimitSpinner_2.Enable =true;

            app.GLowerLimitSpinner.Enable = true;
            app.GUpperLimitSpinner_2.Enable = true;
            app.GLowerLimitSpinner_2.Enable = true;
            app.GUpperLimitSpinner.Enable = true;

            app.BLowerLimitSpinner.Enable = true;
            app.BLowerLimitSpinner_2.Enable = true;
            app.BUpperLimitSpinner.Enable = true;
            app.BUpperLimitSpinner_2.Enable = true;

            app.RestoreDefaultButton.Enable = true;
            app.ApplyChangesButton.Enable = true;
            app.CloseButton.Enable = true;

            app.BrightnessThresholdSpinner.Enable = true;
            app.EdgeconnecterSlider.Enable = true;
            app.DisttoslotlongEditField.Enable = true;
            app.DisttoslotshortEditField.Enable = true;

            results = true;
            return
        end

        function caps = findCaps(app)
            app.disableSliders();
            imD = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            caps = app.capDet.detectCaps(imD);
            return
        end

        function success = changeDiameterLabel(app, newDia)
            txt = "Current cap diameter is " + num2str(newDia) +"  pixels (Average). Would you like to change it?";
            txt = txt+ newline + "(This setting should only be changed when camera position height is changed)";
            app.DiameterLabel.Text = txt;
            success = true;
            return
        end

        function success = changeImage(app, caps)
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(height(caps), 2);
            success = true;
            return
        end

        function success = updateboxdet(app, boxDet)
            app.boxDet = boxDet;
            success = true;
            return
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, camera, cap_detector, colour_detector, box_detector)
%             tcpConnection = Connection("192.168.0.20", 1025);
%             tcpConnection.move2Cam();
            app.disableSliders();
            app.home = callingApp;
            app.cam = camera;
            app.colDet = colour_detector;
            app.capDet = cap_detector;
            app.cam
            app.boxDet = box_detector;
            app.TabGroup.Visible = false;
            txt = "Moving to bottle camera position and detecting the bottles, please wait.";
            app.stateLabel.Text = txt;
            app.home.move2Cam();
            
            txt = "Current cap diameter is " + num2str(app.capDet.dia) +"  pixels (Average). Would you like to change it?";
            txt = txt+ newline + "(This setting should only be changed when camera position height is changed)";
            app.DiameterLabel.Text = txt;

            txt = "Currently, horizontal distance between centroid of box and the edge is " + num2str(app.boxDet.lToEdge) + " pixels.";
            txt = txt + newline + "and vertical distance between centroid of box and the edge is " + num2str(app.boxDet.pToEdge) + " pixels.";
            txt = txt + newline + "Would you like to update them? ";
            txt = txt + newline + "Note that this setting should not be changed unless the camera position height is changed";
            txt = txt + newline + "For details of how to change these values, please visit the help section.";

            app.Label.Text = txt;
            app.BrightnessSlider.Value = app.cam.bright;
            app.ContrastSlider.Value = app.cam.cont;
            app.SaturationSlider.Value = app.cam.sat;
            app.SharpnessSlider.Value = app.cam.sharp;
            if app.cam.gam == 500
                app.GammaSlider.Value = 9;
            else
                app.GammaSlider.Value = app.cam.gam;
            end
            

            app.SensitivitySlider.Value = app.capDet.sens;
            app.EdgeThresholdSlider.Value = app.capDet.edge;

            app.RLowerLimitSpinner.Value = app.colDet.RB(1);
            app.RUpperLimitSpinner.Value = app.colDet.RB(2);
            app.RLowerLimitSpinner_2.Value = app.colDet.RR(1);
            app.RUpperLimitSpinner_2.Value = app.colDet.RR(2);

            app.GLowerLimitSpinner.Value = app.colDet.GB(1);
            app.GUpperLimitSpinner.Value = app.colDet.GB(2);
            app.GLowerLimitSpinner_2.Value = app.colDet.GR(1);
            app.GUpperLimitSpinner_2.Value = app.colDet.GR(2);

            app.BLowerLimitSpinner.Value = app.colDet.BB(1);
            app.BUpperLimitSpinner.Value = app.colDet.BB(2);
            app.BLowerLimitSpinner_2.Value = app.colDet.BR(1);
            app.BUpperLimitSpinner_2.Value = app.colDet.BR(2);
            
            app.BrightnessThresholdSpinner.Value = app.boxDet.brightness;
            app.DisttoslotlongEditField.Value = app.boxDet.longDist2slot;
            app.DisttoslotshortEditField.Value = app.boxDet.shortDist2slot;
            app.EdgeconnecterSlider.Value = app.boxDet.edgeConnecter;

            capL = app.findColours;
%             capL
            if width(capL) == 0
                app.enableSliders();
                imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
                app.previewImage.ImageSource = imN;
                app.changeIcon(width(capL), 1);
                app.TabGroup.Visible = true;
                txt = "If you want to examine the image above, click it.";
                txt = txt + newline + "NOTE: Make sure to press Apply Changes button before exiting.";
                app.stateLabel.Text = txt;
                return
            end
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
            app.TabGroup.Visible = true;
            txt = "If you want to examine the image above, click it.";
            txt = txt + newline + "NOTE: Make sure to press Apply Changes button before exiting.";
            app.stateLabel.Text = txt;
            app.enableSliders();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            try
                enableButtons(app.home);
                delete(app);
            catch
                enableButtons(app.home);
                delete(app);
            end
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                enableButtons(app.home);
                delete(app);
            catch
                enableButtons(app.home);
                delete(app);
            end
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            if app.GammaSlider.Value == 9
                app.cam = app.cam.changeSettings('3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), 500);
            else
                app.cam = app.cam.changeSettings('3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            end
            app.capDet = app.capDet.setVariables(app.SensitivitySlider.Value, app.EdgeThresholdSlider.Value);
            app.boxDet = app.boxDet.setParam(app.DisttoslotlongEditField.Value, app.DisttoslotshortEditField.Value, app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value);
            rb = [app.RLowerLimitSpinner.Value, app.RUpperLimitSpinner.Value];
            gb = [app.GLowerLimitSpinner.Value, app.GUpperLimitSpinner.Value];
            bb = [app.BLowerLimitSpinner.Value, app.BUpperLimitSpinner.Value];
            rr = [app.RLowerLimitSpinner_2.Value, app.RUpperLimitSpinner_2.Value];
            gr = [app.GLowerLimitSpinner_2.Value, app.GUpperLimitSpinner_2.Value];
            br = [app.BLowerLimitSpinner_2.Value, app.BUpperLimitSpinner_2.Value];
            if (rb(1) > rb(2)) || (gb(1) > gb(2)) || (bb(1) > bb(2)) || (rr(1) > rr(2)) || (gr(1) > gr(2)) || (br(1) > br(2))
                f = warndlg("lower limits cannot be greater than the upper limits");
                return 
            else
                app.colDet = app.colDet.setBoundaries(rb, gb, bb, rr, gr, br);
            end
            copyObjects(app.home, app.cam, app.capDet, app.colDet, app.boxDet);
%             app.cam
%             app.capDet
%             app.colDet
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(height(caps), 2);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(height(caps), 2);
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(height(caps), 2);
        end

        % Value changed function: SharpnessSlider
        function SharpnessSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(height(caps), 2);
        end

        % Value changed function: GammaSlider
        function GammaSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(height(caps), 2);
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
            app.changeIcon(height(caps), 2);
        end

        % Value changed function: EdgeThresholdSlider
        function EdgeThresholdSliderValueChanged(app, event)
            caps = app.findCaps();
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps); 
            app.changeIcon(height(caps), 2);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.disableSliders();
            img = app.cam.tempImageAcq(1,'l', '3840x1080', 4, 4, 4, 4, 5);
            dia = app.capDet.obtainDiameter(img);
            currDia = app.capDet.dia;
            app.diaSetter = set_diameter_interface(dia, currDia, app);
        end

        % Button pushed function: RestoreDefaultButton
        function RestoreDefaultButtonPushed(app, event)
            app.disableSliders();
            camDef = app.cam.getDefVars();
            defSens = app.capDet.getDefVars();
            defCol = app.colDet.getDefVars();
            
            app.BrightnessSlider.Value = camDef(1);
            app.ContrastSlider.Value = camDef(2);
            app.SaturationSlider.Value = camDef(3);
            app.SharpnessSlider.Value = camDef(4);
            app.GammaSlider.Value = camDef(5);

            app.SensitivitySlider.Value = defSens(2);
            app.EdgeThresholdSlider.Value = defSens(3);

            app.RLowerLimitSpinner.Value = defCol(1,1);
            app.RUpperLimitSpinner.Value = defCol(1,2);
            app.RLowerLimitSpinner_2.Value = defCol(4,1);
            app.RUpperLimitSpinner_2.Value = defCol(4,2);

            app.GLowerLimitSpinner.Value = defCol(2,1);
            app.GUpperLimitSpinner.Value = defCol(2,2);
            app.GLowerLimitSpinner_2.Value = defCol(5,1);
            app.GUpperLimitSpinner_2.Value = defCol(5,2);

            app.BLowerLimitSpinner.Value = defCol(3,1);
            app.BUpperLimitSpinner.Value = defCol(3,2);
            app.BLowerLimitSpinner_2.Value = defCol(6,1);
            app.BUpperLimitSpinner_2.Value = defCol(6,2);

            [p,l,b,e, lo, sh] = app.boxDet.getDefaultVal();
            
            app.BrightnessThresholdSpinner.Value = b;
            app.DisttoslotlongEditField.Value = lo;
            app.DisttoslotshortEditField.Value = sh;
            app.EdgeconnecterSlider.Value = e;
            app.boxDet = app.boxDet.setDist2Edge(p, l);
            txt = "Currently, horizontal distance between centroid of box and the edge is " + num2str(app.boxDet.lToEdge) + " pixels.";
            txt = txt + newline + "and vertical distance between centroid of box and the edge is " + num2str(app.boxDet.pToEdge) + " pixels.";
            txt = txt + newline + "Would you like to update them? Note that this setting should not be changed unless the camera position height is changed";
            txt = txt + newline + "For details of how to change these values, please visit the help section.";
            app.Label.Text = txt;
            if app.TabGroup.SelectedTab == app.ColourDetectionTab || app.TabGroup.SelectedTab == app.CapDiameterTab || app.TabGroup.SelectedTab == app.CapDetectionTab
                app.home.move2Cam();
                capL = app.findColours;
                imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
                app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
                app.changeIcon(width(capL), 1);
            else
                app.home.move2Box();
                imBox = app.cam.tempImageAcq(1,'l', '3840x1080', 0, 8, 8, 8, 1);
%                 app.boxDet.tempDetectBox(imBox, app.boxDet.pToEdge, app.boxDet.lToEdge,app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value, app.DisttoslotshortEditField.Value, app.DisttoslotlongEditField.Value
                slots = app.boxDet.tempDetectBox(imBox, app.boxDet.pToEdge, app.boxDet.lToEdge,app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value, app.DisttoslotshortEditField.Value, app.DisttoslotlongEditField.Value, 3);
                newImage = app.boxDet.visualiseSlots(imBox, slots);
                app.previewImage.ImageSource = newImage;
                app.changeIcon(height(slots), 3);
            end
            app.enableSliders();
        end

        % Value changed function: RUpperLimitSpinner
        function RUpperLimitSpinnerValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: RLowerLimitSpinner
        function RLowerLimitSpinnerValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GUpperLimitSpinner
        function GUpperLimitSpinnerValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GLowerLimitSpinner
        function GLowerLimitSpinnerValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BUpperLimitSpinner
        function BUpperLimitSpinnerValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BLowerLimitSpinner
        function BLowerLimitSpinnerValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: RUpperLimitSpinner_2
        function RUpperLimitSpinner_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: RLowerLimitSpinner_2
        function RLowerLimitSpinner_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GUpperLimitSpinner_2
        function GUpperLimitSpinner_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: GLowerLimitSpinner_2
        function GLowerLimitSpinner_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BUpperLimitSpinner_2
        function BUpperLimitSpinner_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Value changed function: BLowerLimitSpinner_2
        function BLowerLimitSpinner_2ValueChanged(app, event)
            capL = app.findColours;
            imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
            app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
            app.changeIcon(width(capL), 1);
        end

        % Image clicked function: previewImage
        function previewImageClicked(app, event)
            fig = figure(Visible= "on");
            imshow(app.previewImage.ImageSource);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            txt = "Moving to camera position and detecting the objects, please wait.";
            app.stateLabel.Text = txt;
            if selectedTab == app.BoxDetectionTab || selectedTab == app.BoxLengthTab
                app.disableSliders();
                app.TabGroup.Visible = false;
                app.home.move2Box();
                imBox = app.cam.tempImageAcq(1,'l', '3840x1080', 0,8, 8, 8, 1);
                slots = app.boxDet.tempDetectBox(imBox, app.boxDet.pToEdge, app.boxDet.lToEdge,app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value, app.DisttoslotshortEditField.Value, app.DisttoslotlongEditField.Value, 3);
                newImage = app.boxDet.visualiseSlots(imBox, slots);
                app.previewImage.ImageSource = newImage;
                app.changeIcon(height(slots), 3);
                app.enableSliders();
                app.TabGroup.Visible = true;
            elseif selectedTab == app.ColourDetectionTab
                app.disableSliders();
                app.TabGroup.Visible = false;
                app.home.move2Cam();
                capL = app.findColours;
                imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
                app.previewImage.ImageSource = app.colDet.visualiseAnalysis(capL, imN);
                app.changeIcon(width(capL), 1)
                app.TabGroup.Visible = true;
                app.enableSliders();
            else
                app.disableSliders();
                app.TabGroup.Visible = false;
                app.home.move2Cam();
                caps = app.findCaps();
                imN = app.cam.tempImageAcq(1,'l', '3840x1080', round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(app.GammaSlider.Value));
                app.previewImage.ImageSource = app.capDet.visualiseCaps(imN, caps);
                app.changeIcon(height(caps), 2)
                app.TabGroup.Visible = true;
                app.enableSliders();
            end
            txt = "If you want to examine the image above, click it.";
            txt = txt + newline + "NOTE: Make sure to press Apply Changes button before exiting.";
            app.stateLabel.Text = txt;
        end

        % Value changed function: BrightnessThresholdSpinner
        function BrightnessThresholdSpinnerValueChanged(app, event)
            app.disableSliders();
            app.home.move2Box();
            imBox = app.cam.tempImageAcq(1,'l', '3840x1080', 0,8, 8, 8, 1);
            img = app.boxDet.tempDetectBox(imBox, app.boxDet.pToEdge, app.boxDet.lToEdge,app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value, app.DisttoslotshortEditField.Value, app.DisttoslotlongEditField.Value, 1);
            img = app.bw2rgb(img);
            app.previewImage.ImageSource = img;
            app.enableSliders();
        end

        % Value changed function: EdgeconnecterSlider
        function EdgeconnecterSliderValueChanged(app, event)
            app.disableSliders();
            app.home.move2Box();
            imBox = app.cam.tempImageAcq(1,'l', '3840x1080', 0,8, 8, 8, 1);
            img = app.boxDet.tempDetectBox(imBox, app.boxDet.pToEdge, app.boxDet.lToEdge,app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value, app.DisttoslotshortEditField.Value, app.DisttoslotlongEditField.Value, 2);
            img = app.bw2rgb(img);
            app.previewImage.ImageSource = img;
            app.enableSliders();
        end

        % Value changed function: DisttoslotshortEditField
        function DisttoslotshortEditFieldValueChanged(app, event)
            app.disableSliders();
            app.home.move2Box();
            imBox = app.cam.tempImageAcq(1,'l', '3840x1080', 0,8, 8, 8, 1);
            slots = app.boxDet.tempDetectBox(imBox, app.boxDet.pToEdge, app.boxDet.lToEdge,app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value, app.DisttoslotshortEditField.Value, app.DisttoslotlongEditField.Value, 3);
            newImage = app.boxDet.visualiseSlots(imBox, slots);
            app.previewImage.ImageSource = newImage;
            app.changeIcon(height(slots), 3);
            app.enableSliders();
        end

        % Value changed function: DisttoslotlongEditField
        function DisttoslotlongEditFieldValueChanged(app, event)
            app.disableSliders();
            app.home.move2Box();
            imBox = app.cam.tempImageAcq(1,'l', '3840x1080', 0,8, 8, 8, 1);
            slots = app.boxDet.tempDetectBox(imBox, app.boxDet.pToEdge, app.boxDet.lToEdge,app.BrightnessThresholdSpinner.Value, app.EdgeconnecterSlider.Value, app.DisttoslotshortEditField.Value, app.DisttoslotlongEditField.Value, 3);
            newImage = app.boxDet.visualiseSlots(imBox, slots);
            app.previewImage.ImageSource = newImage;
            app.changeIcon(height(slots), 3);
            app.enableSliders();
        end

        % Button pushed function: ChangeButton_2
        function ChangeButton_2Pushed(app, event)
            app.disableSliders();
            img = app.cam.tempImageAcq(1,'l', '3840x1080', 8, 0, 8, 0, 1);
            [p, l] = app.boxDet.obtainNewDist(img);
            app.lenSetter = set_length_interface(app, app.boxDet, l, p);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CapDetectionTab = uitab(app.TabGroup);
            app.CapDetectionTab.Title = 'Cap Detection';
            app.CameraSettingPanel = uipanel(app.CapDetectionTab);
            app.CameraSettingPanel.Title = 'Camera Setting';
            app.BrightnessSliderLabel = uilabel(app.CameraSettingPanel);
            app.BrightnessSliderLabel.Text = {'Brightness'; ''; ''};
            app.BrightnessSlider = uislider(app.CameraSettingPanel);
            app.BrightnessSlider.Limits = [0 8];
            app.BrightnessSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.ContrastSliderLabel = uilabel(app.CameraSettingPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.CameraSettingPanel);
            app.ContrastSlider.Limits = [0 8];
            app.ContrastSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.SharpnessSliderLabel = uilabel(app.CameraSettingPanel);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.CameraSettingPanel);
            app.SharpnessSlider.Limits = [0 8];
            app.SharpnessSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @SharpnessSliderValueChanged, true);
            app.SaturationSliderLabel = uilabel(app.CameraSettingPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.CameraSettingPanel);
            app.SaturationSlider.Limits = [0 8];
            app.SaturationSlider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.GammaSliderLabel = uilabel(app.CameraSettingPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.CameraSettingPanel);
            app.GammaSlider.Limits = [1 9];
            app.GammaSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @GammaSliderValueChanged, true);
            app.GammaSlider.Value = 1;
            app.SensitivitySettingPanel = uipanel(app.CapDetectionTab);
            app.SensitivitySettingPanel.Title = 'Sensitivity Setting';
            app.SensitivitySliderLabel = uilabel(app.SensitivitySettingPanel);
            app.SensitivitySliderLabel.Text = {'Sensitivity'; ''};
            app.SensitivitySlider = uislider(app.SensitivitySettingPanel);
            app.SensitivitySlider.Limits = [0.9 1];
            app.SensitivitySlider.MajorTicks = [0.9 0.92 0.94 0.96 0.98 1];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Value = 0.9;
            app.EdgeThresholdSliderLabel = uilabel(app.SensitivitySettingPanel);
            app.EdgeThresholdSliderLabel.Text = 'Edge Threshold';
            app.EdgeThresholdSlider = uislider(app.SensitivitySettingPanel);
            app.EdgeThresholdSlider.Limits = [0 0.05];
            app.EdgeThresholdSlider.MajorTicks = [0 0.01 0.02 0.03 0.04 0.05];
            app.EdgeThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EdgeThresholdSliderValueChanged, true);
            app.CapDiameterTab = uitab(app.TabGroup);
            app.CapDiameterTab.Title = 'Cap Diameter';
            app.DiameterLabel = uilabel(app.CapDiameterTab);
            app.DiameterLabel.Text = '';
            app.ChangeButton = uibutton(app.CapDiameterTab, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.ColourDetectionTab = uitab(app.TabGroup);
            app.ColourDetectionTab.Title = 'Colour Detection';
            app.BrownCapRGBlimitsPanel = uipanel(app.ColourDetectionTab);
            app.BrownCapRGBlimitsPanel.Title = 'Brown Cap RGB limits';
            app.RUpperLimitSpinnerLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.RUpperLimitSpinnerLabel.Text = 'R Upper Limit';
            app.RUpperLimitSpinner = uispinner(app.BrownCapRGBlimitsPanel);
            app.RUpperLimitSpinner.Limits = [0 255];
            app.RUpperLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @RUpperLimitSpinnerValueChanged, true);
            app.RLowerLimitSpinnerLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.RLowerLimitSpinnerLabel.Text = 'R Lower Limit';
            app.RLowerLimitSpinner = uispinner(app.BrownCapRGBlimitsPanel);
            app.RLowerLimitSpinner.Limits = [0 255];
            app.RLowerLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @RLowerLimitSpinnerValueChanged, true);
            app.GUpperLimitSpinnerLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.GUpperLimitSpinnerLabel.Text = 'G Upper Limit';
            app.GUpperLimitSpinner = uispinner(app.BrownCapRGBlimitsPanel);
            app.GUpperLimitSpinner.Limits = [0 255];
            app.GUpperLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @GUpperLimitSpinnerValueChanged, true);
            app.GLowerLimitSpinnerLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.GLowerLimitSpinnerLabel.Text = 'G Lower Limit';
            app.GLowerLimitSpinner = uispinner(app.BrownCapRGBlimitsPanel);
            app.GLowerLimitSpinner.Limits = [0 255];
            app.GLowerLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @GLowerLimitSpinnerValueChanged, true);
            app.BUpperLimitSpinnerLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.BUpperLimitSpinnerLabel.Text = 'B Upper Limit';
            app.BUpperLimitSpinner = uispinner(app.BrownCapRGBlimitsPanel);
            app.BUpperLimitSpinner.Limits = [0 255];
            app.BUpperLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @BUpperLimitSpinnerValueChanged, true);
            app.BLowerLimitSpinnerLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.BLowerLimitSpinnerLabel.Text = 'B Lower Limit';
            app.BLowerLimitSpinner = uispinner(app.BrownCapRGBlimitsPanel);
            app.BLowerLimitSpinner.Limits = [0 255];
            app.BLowerLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @BLowerLimitSpinnerValueChanged, true);
            app.PleaseDONOTspamthespinnerarrowLabel = uilabel(app.BrownCapRGBlimitsPanel);
            app.PleaseDONOTspamthespinnerarrowLabel.Text = 'Please DO NOT spam the spinner arrow';
            app.RedCapRGBlimitsPanel = uipanel(app.ColourDetectionTab);
            app.RedCapRGBlimitsPanel.Title = 'Red Cap RGB limits';
            app.RUpperLimitSpinner_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.RUpperLimitSpinner_2Label.Text = 'R Upper Limit';
            app.RUpperLimitSpinner_2 = uispinner(app.RedCapRGBlimitsPanel);
            app.RUpperLimitSpinner_2.Limits = [0 255];
            app.RUpperLimitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RUpperLimitSpinner_2ValueChanged, true);
            app.RLowerLimitSpinner_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.RLowerLimitSpinner_2Label.Text = 'R Lower Limit';
            app.RLowerLimitSpinner_2 = uispinner(app.RedCapRGBlimitsPanel);
            app.RLowerLimitSpinner_2.Limits = [0 255];
            app.RLowerLimitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RLowerLimitSpinner_2ValueChanged, true);
            app.GUpperLimitSpinner_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.GUpperLimitSpinner_2Label.Text = 'G Upper Limit';
            app.GUpperLimitSpinner_2 = uispinner(app.RedCapRGBlimitsPanel);
            app.GUpperLimitSpinner_2.Limits = [0 255];
            app.GUpperLimitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @GUpperLimitSpinner_2ValueChanged, true);
            app.GLowerLimitSpinner_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.GLowerLimitSpinner_2Label.Text = 'G Lower Limit';
            app.GLowerLimitSpinner_2 = uispinner(app.RedCapRGBlimitsPanel);
            app.GLowerLimitSpinner_2.Limits = [0 255];
            app.GLowerLimitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @GLowerLimitSpinner_2ValueChanged, true);
            app.BUpperLimitSpinner_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.BUpperLimitSpinner_2Label.Text = 'B Upper Limit';
            app.BUpperLimitSpinner_2 = uispinner(app.RedCapRGBlimitsPanel);
            app.BUpperLimitSpinner_2.Limits = [0 255];
            app.BUpperLimitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @BUpperLimitSpinner_2ValueChanged, true);
            app.BLowerLimitSpinner_2Label = uilabel(app.RedCapRGBlimitsPanel);
            app.BLowerLimitSpinner_2Label.Text = 'B Lower Limit';
            app.BLowerLimitSpinner_2 = uispinner(app.RedCapRGBlimitsPanel);
            app.BLowerLimitSpinner_2.Limits = [0 255];
            app.BLowerLimitSpinner_2.ValueChangedFcn = createCallbackFcn(app, @BLowerLimitSpinner_2ValueChanged, true);
            app.BoxDetectionTab = uitab(app.TabGroup);
            app.BoxDetectionTab.Title = 'Box Detection';
            app.BrightnessThresholdSpinnerLabel = uilabel(app.BoxDetectionTab);
            app.BrightnessThresholdSpinnerLabel.Text = 'Brightness Threshold';
            app.BrightnessThresholdSpinner = uispinner(app.BoxDetectionTab);
            app.BrightnessThresholdSpinner.Limits = [0 255];
            app.BrightnessThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @BrightnessThresholdSpinnerValueChanged, true);
            app.EdgeconnecterSliderLabel = uilabel(app.BoxDetectionTab);
            app.EdgeconnecterSliderLabel.Text = 'Edge connecter';
            app.EdgeconnecterSlider = uislider(app.BoxDetectionTab);
            app.EdgeconnecterSlider.Limits = [1 12];
            app.EdgeconnecterSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.EdgeconnecterSlider.ValueChangedFcn = createCallbackFcn(app, @EdgeconnecterSliderValueChanged, true);
            app.EdgeconnecterSlider.Value = 1;
            app.DisttoEdgehorizontalLabel = uilabel(app.BoxDetectionTab);
            app.DisttoEdgehorizontalLabel.Text = 'Dist. to slot (short)';
            app.DisttoslotshortEditField = uieditfield(app.BoxDetectionTab, 'numeric');
            app.DisttoslotshortEditField.Limits = [0 1000];
            app.DisttoslotshortEditField.ValueChangedFcn = createCallbackFcn(app, @DisttoslotshortEditFieldValueChanged, true);
            app.DisttoslotlongEditFieldLabel = uilabel(app.BoxDetectionTab);
            app.DisttoslotlongEditFieldLabel.Text = 'Dist. to slot (long)';
            app.DisttoslotlongEditField = uieditfield(app.BoxDetectionTab, 'numeric');
            app.DisttoslotlongEditField.Limits = [0 1000];
            app.DisttoslotlongEditField.ValueChangedFcn = createCallbackFcn(app, @DisttoslotlongEditFieldValueChanged, true);
            app.BoxLengthTab = uitab(app.TabGroup);
            app.BoxLengthTab.Title = 'Box Length';
            app.Label = uilabel(app.BoxLengthTab);
            app.ChangeButton_2 = uibutton(app.BoxLengthTab, 'push');
            app.ChangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_2Pushed, true);
            app.ChangeButton_2.Text = 'Change';
            app.previewImage = uiimage(app.UIFigure);
            app.previewImage.ImageClickedFcn = createCallbackFcn(app, @previewImageClicked, true);
            app.readyIcon = uiimage(app.UIFigure);
            app.readyIcon.ImageSource = 'Colours_ready.png';
            app.RestoreDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoreDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreDefaultButtonPushed, true);
            app.RestoreDefaultButton.Text = 'Restore Default';
            app.ApplyChangesButton = uibutton(app.UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.iconLabel = uilabel(app.UIFigure);
            app.iconLabel.Text = '';
            app.stateLabel = uilabel(app.UIFigure);
            app.stateLabel.Text = 'If you want to examine the image, click the image.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setting_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end