classdef writing_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        CalibrateButton  matlab.ui.control.Button
        EraseButton      matlab.ui.control.Button
        Label            matlab.ui.control.Label
        DoneButton       matlab.ui.control.Button
        Space            matlab.ui.control.EditField
        WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = separateString(app, str ,limit)
            if strlength(str) <= 6
                results = "L," + str;
                return
            end
            str = split(str);
            letterCount = 0;
            newStr = strings(1, 15);
            index = 1;
            small = false;
            firstTime = true;
            h = 1;
            while h <= height(str)
                curStr = str(h);
                letterCount = letterCount + strlength(curStr) + 1;
                if letterCount - 1 < limit
                    if h == height(str)
                        newStr(index) = newStr(index) + curStr;
                    else
                        newStr(index) = newStr(index) + curStr + " ";
                        firstTime = true;
                    end
                    h = h + 1;
                else
                    if firstTime
                        letterCount = 0;
                        index = index + 1;
                        firstTime = false;
                        if h > 1
                            c = char(newStr(index-1));
                            newStr(index-1) = string(c(1:end-1));
                            newStr(index-1) = newStr(index-1) + ",";
                        end
                    else
                        if newStr(index-1) == ""
                            index = index - 1;
                        end
                        newStr(index) = curStr + ",";
                        letterCount = 0;
                        index = index + 1;
                        firstTime = true;
                        if letterCount > limit
                            small = true;
                        end
                        h = h + 1;
                    end
                end
            end
            results = join(newStr,"");
                
            if small 
                results = "S," + results;
            else
                results = "M," + results;
            end
            return
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text = "Welcome, press done to send information to the robot.";
%             app.EraseButton.Enable = false;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.DoneButton.Enable = false;
            app.EraseButton.Enable = false;
            app.Space.Enable = false;
            app.CalibrateButton.Enable = false;
            app.EraseButton.Enable = false;
            str = app.Space.Value;
            if strlength(str) == 0
                warndlg("Make sure the text box is not empty.")
                app.DoneButton.Enable = true;
                app.Space.Enable = true;
                app.CalibrateButton.Enable = true;
                app.EraseButton.Enable = true;
                return
            end
            alph = isletter(str);
            space = isspace(str);
            a = alph(:) == space(:);
            if any(a == true)
                warndlg("The input must not contain special character.")
                app.DoneButton.Enable = true;
                app.Space.Enable = true;
                app.CalibrateButton.Enable = true;
                app.EraseButton.Enable = true;
                return
            end
            
            str2 = app.separateString(str, 10);
            temp = split(str2,",");
            try
                if temp(1) == "M"
                    if height(temp) > 5
                        str2 = app.separateString(str, 19);
                        temp = split(str2);
                        if height(temp) > 8
                            ME = MException("OutOfBoundException", "The input string is too long.");
                            throw(ME);
                        else
                            str2 = split(str2,",");
                            str2(1) = "S";
                            str2 = join(str2, ",");
                        end
                    end
                elseif temp(1) == "S"
                    str2 = app.separateString(str, 19);
                    temp = split(str2);
                    if height(temp) > 8
                        ME = MException("OutOfBoundException", "The input string is too long.");
                        throw(ME);
                    else
                        str2 = split(str2,",");
                        str2(1) = "S";
                        str2 = join(str2, ",");
                    end
                end
            catch
                warndlg("The input string is too long");
                app.DoneButton.Enable = true;
                app.Space.Enable = true;
                app.CalibrateButton.Enable = true;
                app.EraseButton.Enable = true;
                return
            end
            str2 = upper(str2)

            IP = "192.168.0.20";
            port = 1025;
            app.Label.Text = "Connecting to the robot server. Please wait.";
            tcp = tcpclient(IP, port);
            str = "Connected";
            tcp.write(str);
            tcp.flush;
            flag = 0;
            count = 0;
            while flag == 0
                str = read(tcp);
                if char(str) == "RSConnected"
                    break
                end
                count = count+1;
                if count > 30
                    warndlg("Cannot receive signal from server");
                    app.DoneButton.Enable = true;
                    app.CalibrateButton.Enable = true;
                    app.EraseButton.Enable = true;
                    app.Space.Enable = true;
                    clear tcp
                    return
                end
                pause(0.5);
            end
            app.Label.Text = "Connected to server.";
            tcp.write("5");
            flag = 0;
            count = 0;
            while flag == 0
                str = read(tcp);
                if char(str) == "InFive"
                    break
                end
                count = count+0.5;
                if count > 90
                    warndlg("Cannot receive signal from server");
                    app.DoneButton.Enable = true;
                    app.CalibrateButton.Enable = true;
                    app.EraseButton.Enable = true;
                    app.Space.Enable = true;
                    clear tcp
                    return
                end
                pause(0.5);
            end
            txt = "Sending " + str2;
            app.Label.Text = txt;
            tcp.write(str2);
            flag = 0;
            while flag == 0
                str = read(tcp);
                if char(str) == "WriteFinished"
                    break
                end
                pause(0.5);
            end
            clear tcp
            app.DoneButton.Enable = true;
            app.CalibrateButton.Enable = true;
            app.EraseButton.Enable = true;
            app.Space.Enable = true;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            app.DoneButton.Enable = false;
            app.EraseButton.Enable = false;
            app.Space.Enable = false;
            app.CalibrateButton.Enable = false;
            IP = "192.168.0.20";
            port = 1025;
            app.Label.Text = "Connecting to the robot server. Please wait.";
            tcp = tcpclient(IP, port);
            str = "Connected";
            tcp.write(str);
            tcp.flush;
            flag = 0;
            count = 0;
            while flag == 0
                str = read(tcp);
                if char(str) == "RSConnected"
                    break
                end
                count = count+1;
                if count > 30
                    warndlg("Cannot receive signal from server");
                    app.DoneButton.Enable = true;
                    app.EraseButton.Enable = true;
                    app.Space.Enable = true;
                    app.CalibrateButton.Enable = true;
                    clear tcp
                    return
                end
                pause(0.5);
            end
            app.Label.Text = "Connected to server.";
            tcp.write("6");
            flag = 0;
            count = 0;
            while flag == 0
                str = read(tcp);
                if char(str) == "InSix"
                    break
                end
                count = count+1;
                if count > 180
                    warndlg("Cannot receive signal from server");
                    app.DoneButton.Enable = true;
                    app.EraseButton.Enable = true;
                    app.Space.Enable = true;
                    app.CalibrateButton.Enable = true;
                    clear tcp
                    return
                end
                pause(0.5);
            end
            clear tcp
            app.DoneButton.Enable = true;
            app.EraseButton.Enable = true;
            app.Space.Enable = true;
            app.CalibrateButton.Enable = true;

        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.DoneButton.Enable = false;
            app.EraseButton.Enable = false;
            app.Space.Enable = false;
            app.CalibrateButton.Enable = false;
            IP = "192.168.0.20";
            port = 1025;
            app.Label.Text = "Connecting to the robot server. Please wait.";
            tcp = tcpclient(IP, port);
            str = "Connected";
            tcp.write(str);
            tcp.flush;
            flag = 0;
            count = 0;
            while flag == 0
                str = read(tcp);
                if char(str) == "RSConnected"
                    break
                end
                count = count+1;
                if count > 30
                    warndlg("Cannot receive signal from server");
                    app.DoneButton.Enable = true;
                    app.EraseButton.Enable = true;
                    app.Space.Enable = true;
                    app.CalibrateButton.Enable = true;
                    clear tcp
                    return
                end
                pause(0.5);
            end
            app.Label.Text = "Connected to server.";
            tcp.write("8");
            flag = 0;
            count = 0;
            while flag == 0
                str = read(tcp);
                if char(str) == "InEight"
                    break
                end
                count = count+1;
                if count > 180
                    warndlg("Cannot receive signal from server");
                    app.DoneButton.Enable = true;
                    app.EraseButton.Enable = true;
                    app.Space.Enable = true;
                    app.CalibrateButton.Enable = true;
                    clear tcp
                    return
                end
                pause(0.5);
            end
            clear tcp
            app.DoneButton.Enable = true;
            app.EraseButton.Enable = true;
            app.Space.Enable = true;
            app.CalibrateButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel = uilabel(app.UIFigure);
            app.WritedownwhatyouwanttherobottowriteontheboardEditFieldLabel.Text = 'Write down what you want the robot to write on the board';
            app.Space = uieditfield(app.UIFigure, 'text');
            app.Space.Value = 'Sample';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EraseButton = uibutton(app.UIFigure, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Text = 'Erase';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = writing_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end