classdef RGB_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HelpMenu                       matlab.ui.container.Menu
        Image                          matlab.ui.control.Image
        RedCapRGBvalueboundariesPanel  matlab.ui.container.Panel
        Label_12                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        BMaxSlider_2                   matlab.ui.control.Slider
        BMaxSlider_2Label              matlab.ui.control.Label
        BMinSlider_2                   matlab.ui.control.Slider
        BMinSlider_2Label              matlab.ui.control.Label
        GMaxSlider_2                   matlab.ui.control.Slider
        GMaxSlider_2Label              matlab.ui.control.Label
        GMinSlider_2                   matlab.ui.control.Slider
        GMinSlider_2Label              matlab.ui.control.Label
        RMaxSlider_2                   matlab.ui.control.Slider
        RMaxSlider_2Label              matlab.ui.control.Label
        RMinSlider_2                   matlab.ui.control.Slider
        RMinSlider_2Label              matlab.ui.control.Label
        BrownCapRGBvalueboundariesPanel  matlab.ui.container.Panel
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        BMaxSlider                     matlab.ui.control.Slider
        BMaxSliderLabel                matlab.ui.control.Label
        BMinSlider                     matlab.ui.control.Slider
        BMinSliderLabel                matlab.ui.control.Label
        GMaxSlider                     matlab.ui.control.Slider
        GMaxSliderLabel                matlab.ui.control.Label
        GMinSlider                     matlab.ui.control.Slider
        GMinSliderLabel                matlab.ui.control.Label
        RMaxSlider                     matlab.ui.control.Slider
        RMaxSliderLabel                matlab.ui.control.Label
        RMinSlider                     matlab.ui.control.Slider
        RMinSliderLabel                matlab.ui.control.Label
        RestoredefaultButton           matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ApplychangeandexitButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_interface
        colourDet
    end
    
    methods (Access = private)
        
        function results = checkminmax(app)
            if app.RMinSlider.Value > app.RMaxSlider.Value || app.GMinSlider.Value > app.GMaxSlider.Value || app.BMinSlider.Value > app.BMaxSlider.Value
                f = warndlg("Minimum values must be smaller than the maximum values", "warning");
                results = false;
                return 
            elseif app.RMinSlider_2.Value > app.RMaxSlider_2.Value || app.GMinSlider_2.Value > app.GMaxSlider_2.Value || app.BMinSlider_2.Value > app.BMaxSlider_2.Value
                f = warndlg("Minimum values must be smaller than the maximum values", "warning");
                results = false;
                return
            else
                results = true;
                return 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, colour_detector)
            %% make sure to add colour detecter object as an input arg
            app.main_interface = callingApp;
            app.colourDet = colour_detector;
            
            app.RMinSlider.Value = app.colourDet.RboundB(1);
            app.Label.Text = num2str(app.RMinSlider.Value);
            app.RMaxSlider.Value = app.colourDet.RboundB(2);
            app.Label_2.Text = num2str(app.RMaxSlider.Value);

            app.GMinSlider.Value = app.colourDet.GboundB(1);
            app.Label_3.Text = num2str(app.GMinSlider.Value);
            app.GMaxSlider.Value = app.colourDet.GboundB(2);
            app.Label_4.Text = num2str(app.GMaxSlider.Value);
            
            app.BMinSlider.Value = app.colourDet.BboundB(1);
            app.Label_6.Text = num2str(app.BMinSlider.Value);
            app.BMaxSlider.Value = app.colourDet.BboundB(2);
            app.Label_5.Text = num2str(app.BMaxSlider.Value);
            
            app.RMinSlider_2.Value = app.colourDet.RboundR(1);
            app.Label_7.Text = num2str(app.RMinSlider_2.Value);
            app.RMaxSlider_2.Value = app.colourDet.RboundR(2);
            app.Label_8.Text = num2str(app.RMaxSlider_2.Value);
            
            app.GMinSlider_2.Value = app.colourDet.GboundR(1);
            app.Label_9.Text = num2str(app.GMinSlider_2.Value);
            app.GMaxSlider_2.Value = app.colourDet.GboundR(2);
            app.Label_10.Text = num2str(app.GMaxSlider_2.Value);
            
            app.BMinSlider_2.Value = app.colourDet.BboundR(1);
            app.Label_11.Text = num2str(app.BMinSlider_2.Value);
            app.BMaxSlider_2.Value = app.colourDet.BboundR(2);
            app.Label_12.Text = num2str(app.BMaxSlider_2.Value);
        end

        % Button pushed function: ApplychangeandexitButton
        function ApplychangeandexitButtonPushed(app, event)
            %%update values
            if app.checkminmax() == true
                overWriteRGB(app.main_interface, [app.RMinSlider.Value, app.RMaxSlider.Value], [app.GMinSlider.Value, app.GMaxSlider.Value], [app.BMinSlider.Value, app.BMaxSlider.Value], [app.RMinSlider_2.Value, app.RMaxSlider_2.Value], [app.GMinSlider_2.Value, app.GMaxSlider_2.Value], [app.BMinSlider_2.Value, app.BMaxSlider_2.Value]);
                
                enableButtons(app.main_interface)
                delete(app)  
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            enableButtons(app.main_interface);
            delete(app);
        end

        % Button pushed function: RestoredefaultButton
        function RestoredefaultButtonPushed(app, event)
            RB = app.colourDet.getdefRB();
            GB = app.colourDet.getdefGB();
            BB = app.colourDet.getdefBB();
            RR = app.colourDet.getdefRR();
            GR = app.colourDet.getdefGR();
            BR = app.colourDet.getdefBR();
            
            app.RMinSlider.Value = RB(1);
            app.RMaxSlider.Value = RB(2);
            
            app.GMinSlider.Value = GB(1);
            app.GMaxSlider.Value = GB(2);
            
            app.BMinSlider.Value = BB(1);
            app.BMaxSlider.Value = BB(2);
            
            app.RMinSlider_2.Value = RR(1);
            app.RMaxSlider_2.Value = RR(2);
            
            app.GMinSlider_2.Value = GR(1);
            app.GMaxSlider_2.Value = GR(2);
            
            app.BMinSlider_2.Value = BR(1);
            app.BMaxSlider_2.Value = BR(2);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            enableButtons(app.main_interface);
            delete(app);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            RGB_help_interface;
        end

        % Value changing function: RMinSlider
        function RMinSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label.Text = num2str(changingValue);
        end

        % Value changing function: RMaxSlider
        function RMaxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_2.Text = num2str(changingValue);
        end

        % Value changing function: GMinSlider
        function GMinSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = num2str(changingValue);
        end

        % Value changing function: GMaxSlider
        function GMaxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_4.Text = num2str(changingValue);
        end

        % Value changing function: BMinSlider
        function BMinSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_6.Text = num2str(changingValue);
        end

        % Value changing function: BMaxSlider
        function BMaxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_5.Text = num2str(changingValue);
        end

        % Value changing function: RMinSlider_2
        function RMinSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_7.Text = num2str(changingValue);
        end

        % Value changing function: RMaxSlider_2
        function RMaxSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_8.Text = num2str(changingValue);
        end

        % Value changing function: GMinSlider_2
        function GMinSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_9.Text = num2str(changingValue);
        end

        % Value changing function: GMaxSlider_2
        function GMaxSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_10.Text = num2str(changingValue);
        end

        % Value changing function: BMinSlider_2
        function BMinSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_11.Text = num2str(changingValue);
        end

        % Value changing function: BMaxSlider_2
        function BMaxSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_12.Text = num2str(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.ApplychangeandexitButton = uibutton(app.UIFigure, 'push');
            app.ApplychangeandexitButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangeandexitButtonPushed, true);
            app.ApplychangeandexitButton.Text = 'Apply change and exit';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = {'Cancel'; ''};
            app.RestoredefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultButtonPushed, true);
            app.RestoredefaultButton.Text = 'Restore default';
            app.BrownCapRGBvalueboundariesPanel = uipanel(app.UIFigure);
            app.BrownCapRGBvalueboundariesPanel.Title = 'Brown Cap RGB value boundaries';
            app.RMinSliderLabel = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.RMinSliderLabel.Text = 'R Min';
            app.RMinSlider = uislider(app.BrownCapRGBvalueboundariesPanel);
            app.RMinSlider.Limits = [0 255];
            app.RMinSlider.ValueChangingFcn = createCallbackFcn(app, @RMinSliderValueChanging, true);
            app.RMaxSliderLabel = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.RMaxSliderLabel.Text = 'R Max';
            app.RMaxSlider = uislider(app.BrownCapRGBvalueboundariesPanel);
            app.RMaxSlider.Limits = [0 255];
            app.RMaxSlider.ValueChangingFcn = createCallbackFcn(app, @RMaxSliderValueChanging, true);
            app.GMinSliderLabel = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.GMinSliderLabel.Text = 'G Min';
            app.GMinSlider = uislider(app.BrownCapRGBvalueboundariesPanel);
            app.GMinSlider.Limits = [0 255];
            app.GMinSlider.ValueChangingFcn = createCallbackFcn(app, @GMinSliderValueChanging, true);
            app.GMaxSliderLabel = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.GMaxSliderLabel.Text = 'G Max';
            app.GMaxSlider = uislider(app.BrownCapRGBvalueboundariesPanel);
            app.GMaxSlider.Limits = [0 255];
            app.GMaxSlider.ValueChangingFcn = createCallbackFcn(app, @GMaxSliderValueChanging, true);
            app.BMinSliderLabel = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.BMinSliderLabel.Text = 'B Min';
            app.BMinSlider = uislider(app.BrownCapRGBvalueboundariesPanel);
            app.BMinSlider.Limits = [0 255];
            app.BMinSlider.ValueChangingFcn = createCallbackFcn(app, @BMinSliderValueChanging, true);
            app.BMaxSliderLabel = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.BMaxSliderLabel.Text = 'B Max';
            app.BMaxSlider = uislider(app.BrownCapRGBvalueboundariesPanel);
            app.BMaxSlider.Limits = [0 255];
            app.BMaxSlider.ValueChangingFcn = createCallbackFcn(app, @BMaxSliderValueChanging, true);
            app.Label = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.Label_2 = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.Label_3 = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.Label_4 = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.Label_5 = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.Label_6 = uilabel(app.BrownCapRGBvalueboundariesPanel);
            app.RedCapRGBvalueboundariesPanel = uipanel(app.UIFigure);
            app.RedCapRGBvalueboundariesPanel.Title = 'Red Cap RGB value boundaries';
            app.RMinSlider_2Label = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.RMinSlider_2Label.Text = 'R Min';
            app.RMinSlider_2 = uislider(app.RedCapRGBvalueboundariesPanel);
            app.RMinSlider_2.Limits = [0 255];
            app.RMinSlider_2.ValueChangingFcn = createCallbackFcn(app, @RMinSlider_2ValueChanging, true);
            app.RMaxSlider_2Label = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.RMaxSlider_2Label.Text = 'R Max';
            app.RMaxSlider_2 = uislider(app.RedCapRGBvalueboundariesPanel);
            app.RMaxSlider_2.Limits = [0 255];
            app.RMaxSlider_2.ValueChangingFcn = createCallbackFcn(app, @RMaxSlider_2ValueChanging, true);
            app.GMinSlider_2Label = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.GMinSlider_2Label.Text = 'G Min';
            app.GMinSlider_2 = uislider(app.RedCapRGBvalueboundariesPanel);
            app.GMinSlider_2.Limits = [0 255];
            app.GMinSlider_2.ValueChangingFcn = createCallbackFcn(app, @GMinSlider_2ValueChanging, true);
            app.GMaxSlider_2Label = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.GMaxSlider_2Label.Text = 'G Max';
            app.GMaxSlider_2 = uislider(app.RedCapRGBvalueboundariesPanel);
            app.GMaxSlider_2.Limits = [0 255];
            app.GMaxSlider_2.ValueChangingFcn = createCallbackFcn(app, @GMaxSlider_2ValueChanging, true);
            app.BMinSlider_2Label = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.BMinSlider_2Label.Text = 'B Min';
            app.BMinSlider_2 = uislider(app.RedCapRGBvalueboundariesPanel);
            app.BMinSlider_2.Limits = [0 255];
            app.BMinSlider_2.ValueChangingFcn = createCallbackFcn(app, @BMinSlider_2ValueChanging, true);
            app.BMaxSlider_2Label = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.BMaxSlider_2Label.Text = 'B Max';
            app.BMaxSlider_2 = uislider(app.RedCapRGBvalueboundariesPanel);
            app.BMaxSlider_2.Limits = [0 255];
            app.BMaxSlider_2.ValueChangingFcn = createCallbackFcn(app, @BMaxSlider_2ValueChanging, true);
            app.Label_7 = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.Label_9 = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.Label_8 = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.Label_10 = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.Label_11 = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.Label_12 = uilabel(app.RedCapRGBvalueboundariesPanel);
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGB_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end