classdef camera_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        HelpMenu                 matlab.ui.container.Menu
        PreviewButton            matlab.ui.control.Button
        GammaDropDown            matlab.ui.control.DropDown
        GammaDropDownLabel       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        BrightnessSlider         matlab.ui.control.Slider
        BrightnessSliderLabel    matlab.ui.control.Label
        RestoredefaultButton     matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        ProceedButton            matlab.ui.control.Button
        ResolutionDropDown       matlab.ui.control.DropDown
        ResolutionDropDownLabel  matlab.ui.control.Label
        SharpnessSlider          matlab.ui.control.Slider
        SharpnessSliderLabel     matlab.ui.control.Label
        SaturationSlider         matlab.ui.control.Slider
        SaturationSliderLabel    matlab.ui.control.Label
        ContrastSlider           matlab.ui.control.Slider
        ContrastSliderLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_interface
        camera% Description
        help_interface
    end
    
    methods (Access = private)
        
        function results = disableComponents(app)
            app.BrightnessSlider.Enable = false;
            app.ContrastSlider.Enable = false;
            app.SaturationSlider.Enable = false;
            app.SharpnessSlider.Enable = false;
            app.ResolutionDropDown.Enable = false;
            app.GammaDropDown.Enable = false;
            results = true;
            return
        end

        function results = enableComponents(app)
            app.BrightnessSlider.Enable = true;
            app.ContrastSlider.Enable = true;
            app.SaturationSlider.Enable = true;
            app.SharpnessSlider.Enable = true;
            app.ResolutionDropDown.Enable = true;
            app.GammaDropDown.Enable = true;
            results = true;
            return
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, cam)
            app.main_interface = callingApp;
            app.camera = cam;
            app.camera
            app.ResolutionDropDown.Value = app.camera.resolution;
            app.BrightnessSlider.Value = app.camera.brightness;
            app.ContrastSlider.Value = app.camera.contrast;
            app.SaturationSlider.Value = app.camera.saturation;
            app.SharpnessSlider.Value = app.camera.sharpness;
            app.GammaDropDown.Value = num2str(app.camera.gamma);
            try
                app.Image.ImageSource = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, ...
                    round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                return
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            try
                overWirteCam(app.main_interface, app.ResolutionDropDown.Value, round(app.BrightnessSlider.Value), ...
                    round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
                enableButtons(app.main_interface);
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            try
                enableButtons(app.main_interface);
                delete(app)
            catch
                delete(app)
            end
        end

        % Button pushed function: RestoredefaultButton
        function RestoredefaultButtonPushed(app, event)
            app.disableComponents();
            app.ResolutionDropDown.Value = app.camera.getDefRes();
            app.BrightnessSlider.Value = app.camera.getDefBri();
            app.ContrastSlider.Value = app.camera.getDefCont();
            app.SaturationSlider.Value = app.camera.getDefSat();
            app.SharpnessSlider.Value = app.camera.getDefShar();
            app.GammaDropDown.Value = num2str(app.camera.getDefGam());
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                enableButtons(app.main_interface);
                delete(app)
            catch
                delete(app)
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.help_interface = camera_help_interface;
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            app.disableComponents();
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                app.enableComponents();
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            app.disableComponents();
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                app.enableComponents();
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            app.disableComponents();
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                app.enableComponents();
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end

        % Value changed function: SharpnessSlider
        function SharpnessSliderValueChanged(app, event)
            app.disableComponents();
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                app.enableComponents();
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            app.disableComponents();
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                app.enableComponents();
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end

        % Value changed function: GammaDropDown
        function GammaDropDownValueChanged(app, event)
            app.disableComponents();
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                app.enableComponents();
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.disableComponents();
            try
                img = app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value), round(str2double(app.GammaDropDown.Value)));
            catch
                f = warndlg("Camera is not connected to this PC or used in other software", "Cannot connect to webcam");
                app.enableComponents();
                return
            end
            app.Image.ImageSource = img;
            app.enableComponents();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 8];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.SaturationSliderLabel = uilabel(app.UIFigure);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.UIFigure);
            app.SaturationSlider.Limits = [0 8];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaturationSlider.MinorTicks = [];
            app.SharpnessSliderLabel = uilabel(app.UIFigure);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.UIFigure);
            app.SharpnessSlider.Limits = [0 8];
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @SharpnessSliderValueChanged, true);
            app.SharpnessSlider.MinorTicks = [];
            app.ResolutionDropDownLabel = uilabel(app.UIFigure);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.UIFigure);
            app.ResolutionDropDown.Items = {'4416x1242', '3840x1080', '2560x720', '1344x376'};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Value = '2560x720';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RestoredefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultButtonPushed, true);
            app.RestoredefaultButton.Text = 'Restore default';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [0 8];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.Image = uiimage(app.UIFigure);
            app.GammaDropDownLabel = uilabel(app.UIFigure);
            app.GammaDropDownLabel.Text = 'Gamma';
            app.GammaDropDown = uidropdown(app.UIFigure);
            app.GammaDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.GammaDropDown.ValueChangedFcn = createCallbackFcn(app, @GammaDropDownValueChanged, true);
            app.GammaDropDown.Value = '1';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camera_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end