classdef main_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisResult                matlab.ui.Figure
        SettingMenu                   matlab.ui.container.Menu
        CamerasettingMenu             matlab.ui.container.Menu
        CapdiametersettingMenu        matlab.ui.container.Menu
        CapdetectionsensitivitysettingMenu  matlab.ui.container.Menu
        RGBboundarysettingMenu        matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        QuitButton                    matlab.ui.control.Button
        sampleImage                   matlab.ui.control.Image
        stateLabel                    matlab.ui.control.Label
        RunpalletizingsequenceButton  matlab.ui.control.Button
        OthertasksButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        RBGApp
        senseApp% Description
        helpApp
        otherApp
        diamterApp
        cameraApp

        capDetector
        camera
        colourDetector
        
        tcpConnection
        IP
        port
        connectionFlag
        runningFlag

        centrePic
    end
       
    methods (Access = public)
        
        function results = enableButtons(app)
            app.RunpalletizingsequenceButton.Enable = true;
            app.OthertasksButton.Enable = true;
            app.SettingMenu.Enable = true;
            results = true;
            return
        end

        function result = overwriteSense(app, capDet)
            app.capDetector = app.capDetector.setVariables(capDet.sensitivity, capDet.edgethresh);
            app.capDetector
            result = true;
            return
        end
        function result = overwriteDia(app, newDia)
            app.capDetector = app.capDetector.setDia(newDia);
            app.capDetector
            result = true;
            return
        end
        
        function result = overWirteCam(app, newRes,newBri, newCont, newSat, newShar, newGam)
            app.camera = app.camera.setVars(newRes, newBri, newCont, newSat, newShar, newGam);
            app.camera
            result = true;
        end
        
        function result = overWriteRGB(app, newRB, newGB, newBB, newRR, newGR, newBR)
            app.colourDetector = app.colourDetector.setBound(newRB, newGB, newBB, newRR, newGR, newBR);
            app.colourDetector
            result = true;
        end

        function connectToTCP(app)
            app.connectionFlag = 0;
            app.tcpConnection = tcpclient(app.IP,app.port);
            app.tcpConnection.write('Connected');
            app.tcpConnection.flush;
            
            while app.connectionFlag == 0
                test = read(app.tcpConnection);
                if char(test) == "RSConnected"
                    app.connectionFlag = 1;
                    break
                end
                pause(0.5);
            end
            app.connectionFlag = 0;
        end

        function move2Cam(app)
            app.tcpConnection.write("1");
            app.tcpConnection.flush();
            count = 0;
            while app.connectionFlag == 0
                test = read(app.tcpConnection);
                if char(test) == "CamPos"
                    app.connectionFlag = 1;
                    break
                end
                pause(0.5);
                count = count+1;
                if count > 15
                    ME = MException("receivingDataError", "Error receiving RobotStudio.");
                    throw(ME);
                end
            end
            app.connectionFlag = 0;
            clear app.tcpConnection
        end

        function move2Bot(app, coord_data)
            app.tcpConnection.write("2");
            app.tcpConnection.flush();
            pause(1);
            for h = 1:height(coord_data)
                app.tcpConnection.write(coord_data(h,:));
                app.tcpConnection.flush();
                while app.connectionFlag == 0
                    test = read(app.tcpConnection);
                    if char(test) == "SendNext"
                        centres2 = app.capDetector.automaticDetection(app.camera.manualImageAcq());
                        minDif = Inf;
                        ind = 0;
                        for h2 = 1: height(centres2)
                            currPos = centres2(h2,:);
                            posDif = currPos - app.centrePic;
                            posDif = abs(posDif(1)) + abs(posDif(2));
                            if posDif < minDif
                                minDif = posDif;
                                ind = h2;
                            end
                        end
                        %detectColour and generate coordinate;
                        %add code
                        %dummy coordinate, delete later
                        coord_data2 = "B,-81.3,-293.45";
                        app.tcpConnection.write(coord_data2);
                        app.tcpConnection.flush();
                        app.connectionFlag = 1;
                        while(app.connectionFlag == 1)
                            test = read(app.tcpConnection);
                            if char(test) == "SendNext2"
                                app.connectionFlag = 0;      
                            end
                            pause(0.5);
                        end
                        app.connectionFlag = 1;
                    end
                    pause(0.5);
                end
                app.connectionFlag = 0;
            end
            app.tcpConnection.write("3");
            app.tcpConnection.flush();
            clear app.tcpConnection
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.capDetector = cap_detection(0.9, 0.05, 81);
            app.colourDetector = colour_detection([250 255], [200 255], [0 40], [245, 255], [0 120], [0 10]);
            app.camera = photo_acquisition('4416x1242', 1);
            app.centrePic = [1104, 621];
            app.IP = "192.168.0.20";
            app.port = 1025;
            app.connectionFlag = 0;
            app.runningFlag = false;
% fig = openfig("Success.fig", "invisible");
%             saveas(fig,"sampleFig.png")
% %             a = findobj(fig, 'Type',"Image");
% %             copyobj(a.Children, app.AnalysisResult);
% %             delete(fig)
%             im = imread('samplefig.png');
%             app.sampleImage.ImageSource = im;
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.helpApp = help_interface;
        end

        % Menu selected function: RGBboundarysettingMenu
        function RGBboundarysettingMenuSelected(app, event)
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            %% make sure to add colour detecter object as an input arg
            app.RBGApp = RGB_interface(app, app.colourDetector);
        end

        % Menu selected function: CapdetectionsensitivitysettingMenu
        function CapdetectionsensitivitysettingMenuSelected(app, event)
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            %% make sure to add circle detecter object as an input arg
            app.senseApp = sense_interface(app, app.capDetector, app.camera);
        end

        % Button pushed function: OthertasksButton
        function OthertasksButtonPushed(app, event)
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            app.otherApp = other_interface(app,app.camera);
        end

        % Menu selected function: CapdiametersettingMenu
        function CapdiametersettingMenuSelected(app, event)
            %%after that
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            pause(0.5)
             %%
            % TCP connection
            try
                app.connectToTCP();
            catch
                app.stateLabel.Text = "There was an error connecting to RobotStudio. Please ensure that script is running on RobotStudio.";
                app.enableButtons;
                return
            end
            %%
            %move robot to camera pos.
            try
                app.move2Cam();
            catch
                app.stateLabel.Text = "There was an error communicating with with RobotStudio, please try again";
                app.enableButtons;
                return
            end

            try
                dia = app.capDetector.obtainDiameter(app.camera.manualImageAcq());
            catch
                app.stateLabel.Text = "Cannot connect to camera. ensure that the camera is connected via USB to computer or not been used by other software";
                app.enableButtons;
                return
            end
            app.diamterApp = diameter_interface(app,app.capDetector,dia);
        end

        % Menu selected function: CamerasettingMenu
        function CamerasettingMenuSelected(app, event)
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            
            app.cameraApp = camera_interface(app, app.camera);
        end

        % Close request function: AnalysisResult
        function AnalysisResultCloseRequest(app, event)
            %%
            % TCP connection
            if app.runningFlag == false
                failed = false;
                try
                    app.connectToTCP()
                    app.tcpConnection.write("0");
                    app.tcpConnection.flush();
                    clear app.tcpConnection
                catch 
                    failed = true;
                    app.stateLabel.Text="Closing software in 3 seconds";
                    pause(1);
                    app.stateLabel.Text="Closing software in 2 seconds";
                    pause(1);
                    app.stateLabel.Text="Closing software in 1 seconds";
                    pause(1);
    
                    delete(app.cameraApp)
                    delete(app.diamterApp)
                    delete(app.RBGApp)
                    delete(app.senseApp)
                    delete(app.helpApp)
                    delete(app.otherApp)
                    delete(app)
                end
                if failed == false
                    delete(app.cameraApp)
                    delete(app.diamterApp)
                    delete(app.RBGApp)
                    delete(app.senseApp)
                    delete(app.helpApp)
                    delete(app.otherApp)
                    delete(app)
                end
            else
                clear app.tcpConnection
                delete(app.cameraApp)
                delete(app.diamterApp)
                delete(app.RBGApp)
                delete(app.senseApp)
                delete(app.helpApp)
                delete(app.otherApp)
                delete(app)
            end
        
            
        end

        % Button pushed function: RunpalletizingsequenceButton
        function RunpalletizingsequenceButtonPushed(app, event)
            app.runningFlag = true;
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            %%
            % TCP connection
            try
                app.connectToTCP();
            catch
                app.stateLabel.Text = "There was an error connecting to RobotStudio. Please ensure that script is running on RobotStudio.";
                app.enableButtons;
                return
            end
            %%
            %move robot to camera pos.
            try
                app.move2Cam();
            catch
                app.stateLabel.Text = "There was an error communicating with with RobotStudio, please try again";
                app.enableButtons;
                return
            end
            %%
            %detecting caps
            app.stateLabel.Text = "Detecting bottle caps, please wait";
            try
                centres = app.capDetector.automaticDetection(app.camera.manualImageAcq());
            catch
                app.stateLabel.Text = "Cannot connect to camera. ensure that the camera is connected via USB to computer and not been used by other software";
                app.enableButtons;
                return
            end
            im = imread('detectedCaps.png');
            app.sampleImage.ImageSource = im;
            app.stateLabel.Text = "There are " + num2str(height(centres)) + " caps detected.";
            %%
            %detect colour and convert coordinate to robot coord.
            %add code

            %%
            % TCP connection
            try
                app.connectToTCP();
            catch
                app.stateLabel.Text = "There was an error connecting to RobotStudio. Please ensure that script is running on RobotStudio.";
                app.enableButtons;
                return
            end
            %%
            %sending coordiante data
            %dummy coordinate, delete later
            coord_data = ["B,-81.3,-293.45";"R,-90,-180"];
            app.move2Bot(coord_data);
            app.runningFlag = false;
            app.enableButtons();
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            clear app.tcpConnection
            app.stateLabel.Text = "Please wait for the previous command to execute before closing";
            delete(app.cameraApp)
            delete(app.diamterApp)
            delete(app.RBGApp)
            delete(app.senseApp)
            delete(app.helpApp)
            delete(app.otherApp)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisResult = uifigure('Visible', 'off');
            app.AnalysisResult.Name = 'MATLAB App';
            app.AnalysisResult.CloseRequestFcn = createCallbackFcn(app, @AnalysisResultCloseRequest, true);
            app.SettingMenu = uimenu(app.AnalysisResult);
            app.SettingMenu.Text = 'Setting';
            app.CamerasettingMenu = uimenu(app.SettingMenu);
            app.CamerasettingMenu.MenuSelectedFcn = createCallbackFcn(app, @CamerasettingMenuSelected, true);
            app.CamerasettingMenu.Text = 'Camera setting';
            app.CapdiametersettingMenu = uimenu(app.SettingMenu);
            app.CapdiametersettingMenu.MenuSelectedFcn = createCallbackFcn(app, @CapdiametersettingMenuSelected, true);
            app.CapdiametersettingMenu.Text = 'Cap diameter setting';
            app.CapdetectionsensitivitysettingMenu = uimenu(app.SettingMenu);
            app.CapdetectionsensitivitysettingMenu.MenuSelectedFcn = createCallbackFcn(app, @CapdetectionsensitivitysettingMenuSelected, true);
            app.CapdetectionsensitivitysettingMenu.Text = 'Cap detection sensitivity setting';
            app.RGBboundarysettingMenu = uimenu(app.SettingMenu);
            app.RGBboundarysettingMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBboundarysettingMenuSelected, true);
            app.RGBboundarysettingMenu.Text = 'RGB boundary setting';
            app.HelpMenu = uimenu(app.AnalysisResult);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.OthertasksButton = uibutton(app.AnalysisResult, 'push');
            app.OthertasksButton.ButtonPushedFcn = createCallbackFcn(app, @OthertasksButtonPushed, true);
            app.OthertasksButton.Text = 'Other tasks';
            app.RunpalletizingsequenceButton = uibutton(app.AnalysisResult, 'push');
            app.RunpalletizingsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @RunpalletizingsequenceButtonPushed, true);
            app.RunpalletizingsequenceButton.Text = 'Run palletizing sequence';
            app.stateLabel = uilabel(app.AnalysisResult);
            app.stateLabel.Text = '';
            app.sampleImage = uiimage(app.AnalysisResult);
            app.sampleImage.ImageSource = 'welcometo.png';
            app.QuitButton = uibutton(app.AnalysisResult, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.AnalysisResult.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisResult)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisResult)
        end
    end
end